classdef GUI_Updated < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        uipanel4        matlab.ui.container.Panel
        pushbutton51    matlab.ui.control.Button
        pushbutton52    matlab.ui.control.Button
        pushbutton53    matlab.ui.control.Button
        pushbutton54    matlab.ui.control.Button
        pushbutton55    matlab.ui.control.Button
        pushbutton56    matlab.ui.control.Button
        pushbutton57    matlab.ui.control.Button
        pushbutton58    matlab.ui.control.Button
        pushbutton59    matlab.ui.control.Button
        pushbutton60    matlab.ui.control.Button
        pushbutton61    matlab.ui.control.Button
        uipanel3        matlab.ui.container.Panel
        pushbutton26    matlab.ui.control.Button
        pushbutton27    matlab.ui.control.Button
        pushbutton28    matlab.ui.control.Button
        pushbutton25    matlab.ui.control.Button
        pushbutton29    matlab.ui.control.Button
        pushbutton30    matlab.ui.control.Button
        pushbutton31    matlab.ui.control.Button
        pushbutton32    matlab.ui.control.Button
        pushbutton33    matlab.ui.control.Button
        pushbutton34    matlab.ui.control.Button
        pushbutton35    matlab.ui.control.Button
        uipanel5        matlab.ui.container.Panel
        pushbutton39    matlab.ui.control.Button
        pushbutton40    matlab.ui.control.Button
        pushbutton41    matlab.ui.control.Button
        pushbutton42    matlab.ui.control.Button
        pushbutton43    matlab.ui.control.Button
        pushbutton44    matlab.ui.control.Button
        pushbutton45    matlab.ui.control.Button
        pushbutton46    matlab.ui.control.Button
        pushbutton47    matlab.ui.control.Button
        pushbutton48    matlab.ui.control.Button
        pushbutton49    matlab.ui.control.Button
        uipanel1        matlab.ui.container.Panel
        pushbutton2     matlab.ui.control.Button
        pushbutton3     matlab.ui.control.Button
        pushbutton4     matlab.ui.control.Button
        pushbutton5     matlab.ui.control.Button
        pushbutton6     matlab.ui.control.Button
        pushbutton7     matlab.ui.control.Button
        pushbutton8     matlab.ui.control.Button
        pushbutton9     matlab.ui.control.Button
        pushbutton10    matlab.ui.control.Button
        pushbutton11    matlab.ui.control.Button
        pushbutton23    matlab.ui.control.Button
        Image7          matlab.ui.control.Image
        Image7_2        matlab.ui.control.Image
        Image7_5        matlab.ui.control.Image
        Image7_6        matlab.ui.control.Image
        Image7_7        matlab.ui.control.Image
        Image7_8        matlab.ui.control.Image
        Image7_9        matlab.ui.control.Image
        Image7_10       matlab.ui.control.Image
        Image7_11       matlab.ui.control.Image
        Image7_12       matlab.ui.control.Image
        Image7_13       matlab.ui.control.Image
        Image           matlab.ui.control.Image
        Image2          matlab.ui.control.Image
        Image3          matlab.ui.control.Image
        Image4          matlab.ui.control.Image
        Image5          matlab.ui.control.Image
        Image6          matlab.ui.control.Image
        PenaltiesLabel  matlab.ui.control.Label
        NLabel          matlab.ui.control.Label
        Image7_4        matlab.ui.control.Image
        Image7_3        matlab.ui.control.Image
        Image7_14       matlab.ui.control.Image
        Image7_15       matlab.ui.control.Image
        Image7_16       matlab.ui.control.Image
        Image7_17       matlab.ui.control.Image
        Image7_18       matlab.ui.control.Image
        Image7_19       matlab.ui.control.Image
        Image7_20       matlab.ui.control.Image
        Image7_21       matlab.ui.control.Image
        Image7_22       matlab.ui.control.Image
        Image7_23       matlab.ui.control.Image
        Image7_24       matlab.ui.control.Image
        Image7_25       matlab.ui.control.Image
        Image7_26       matlab.ui.control.Image
        Image7_27       matlab.ui.control.Image
        Image7_28       matlab.ui.control.Image
        Image7_29       matlab.ui.control.Image
        Image7_30       matlab.ui.control.Image
        Image7_31       matlab.ui.control.Image
        Image7_32       matlab.ui.control.Image
        Image7_33       matlab.ui.control.Image
        Image7_34       matlab.ui.control.Image
        Image7_35       matlab.ui.control.Image
        Image7_36       matlab.ui.control.Image
        Image7_37       matlab.ui.control.Image
        Image7_38       matlab.ui.control.Image
        Image7_39       matlab.ui.control.Image
        Image7_40       matlab.ui.control.Image
        Image7_41       matlab.ui.control.Image
        Image7_42       matlab.ui.control.Image
        Image7_43       matlab.ui.control.Image
        Image7_44       matlab.ui.control.Image
    end

    
    properties (Access = private)
        game = game(); % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_Boardgame_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI_Boardgame (see VARARGIN)
            
            % Choose default command line output for GUI_Boardgame
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
        end

        % Button pushed function: pushbutton2
        function pushbutton2ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",1);
            if(success)
                app.Image7.Visible=true;
            end
        end

        % Button pushed function: pushbutton3
        function pushbutton3ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",2);
            if(success)
                app.Image7_2.Visible=true;
            end
        end

        % Button pushed function: pushbutton4
        function pushbutton4ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",3);
            if(success)
                app.Image7_5.Visible=true;
            end
        end

        % Button pushed function: pushbutton5
        function pushbutton5ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",4);
            if(success)
                app.Image7_6.Visible=true;
            end
        end

        % Button pushed function: pushbutton6
        function pushbutton6ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",5);
            if(success)
                app.Image7_7.Visible=true;
            end
        end

        % Button pushed function: pushbutton7
        function pushbutton7ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",6);
            if(success)
                app.Image7_8.Visible=true;
            end
        end

        % Button pushed function: pushbutton8
        function pushbutton8ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",7);
            if(success)
                app.Image7_9.Visible=true;
            end
        end

        % Button pushed function: pushbutton9
        function pushbutton9ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",8);
            if(success)
                app.Image7_10.Visible=true;
            end
        end

        % Button pushed function: pushbutton10
        function pushbutton10ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",9);
            if(success)
                app.Image7_11.Visible=true;
            end
        end

        % Button pushed function: pushbutton11
        function pushbutton11ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",10);
            if(success)
                app.Image7_12.Visible=true;
            end
        end

        % Button pushed function: pushbutton23
        function pushbutton23ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("red",11);
            if(success)
                app.Image7_13.Visible=true;
            end
        end

        % Button pushed function: pushbutton25
        function pushbutton25ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",1);
            if(success)
                app.Image7_23.Visible=true;
            end
        end

        % Button pushed function: pushbutton26
        function pushbutton26ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",2);
            if(success)
                app.Image7_24.Visible=true;
            end
        end

        % Button pushed function: pushbutton27
        function pushbutton27ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",3);
            if(success)
                app.Image7_25.Visible=true;
            end
        end

        % Button pushed function: pushbutton28
        function pushbutton28ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",4);
            if(success)
                app.Image7_26.Visible=true;
            end
        end

        % Button pushed function: pushbutton29
        function pushbutton29ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",5);
            if(success)
                app.Image7_27.Visible=true;
            end
        end

        % Button pushed function: pushbutton30
        function pushbutton30ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",6);
            if(success)
                app.Image7_28.Visible=true;
            end
        end

        % Button pushed function: pushbutton31
        function pushbutton31ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",7);
            if(success)
                app.Image7_29.Visible=true;
            end
        end

        % Button pushed function: pushbutton32
        function pushbutton32ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",8);
            if(success)
                app.Image7_30.Visible=true;
            end
        end

        % Button pushed function: pushbutton33
        function pushbutton33ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",9);
            if(success)
                app.Image7_31.Visible=true;
            end
        end

        % Button pushed function: pushbutton34
        function pushbutton34ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",10);
            if(success)
                app.Image7_32.Visible=true;
            end
        end

        % Button pushed function: pushbutton35
        function pushbutton35ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("yellow",11);
            if(success)
                app.Image7_33.Visible=true;
            end
        end

        % Button pushed function: pushbutton39
        function pushbutton39ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",1);
            if(success)
                app.Image7_4.Visible=true;
            end
        end

        % Button pushed function: pushbutton40
        function pushbutton40ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",2);
            if(success)
                app.Image7_3.Visible=true;
            end
        end

        % Button pushed function: pushbutton41
        function pushbutton41ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",3);
            if(success)
                app.Image7_14.Visible=true;
            end
        end

        % Button pushed function: pushbutton42
        function pushbutton42ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",4);
            if(success)
                app.Image7_15.Visible=true;
            end
        end

        % Button pushed function: pushbutton43
        function pushbutton43ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",5);
            if(success)
                app.Image7_16.Visible=true;
            end
        end

        % Button pushed function: pushbutton44
        function pushbutton44ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",6);
            if(success)
                app.Image7_17.Visible=true;
            end
        end

        % Button pushed function: pushbutton45
        function pushbutton45ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",7);
            if(success)
                app.Image7_18.Visible=true;
            end
        end

        % Button pushed function: pushbutton46
        function pushbutton46ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",8);
            if(success)
                app.Image7_19.Visible=true;
            end
        end

        % Button pushed function: pushbutton47
        function pushbutton47ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",9);
            if(success)
                app.Image7_20.Visible=true;
            end
        end

        % Button pushed function: pushbutton48
        function pushbutton48ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",10);
            if(success)
                app.Image7_21.Visible=true;
            end
        end

        % Button pushed function: pushbutton49
        function pushbutton49ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("green",11);
            if(success)
                app.Image7_22.Visible=true;
            end
        end

        % Button pushed function: pushbutton51
        function pushbutton51ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",1);
            if(success)
                app.Image7_34.Visible=true;
            end
        end

        % Button pushed function: pushbutton52
        function pushbutton52ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",2);
            if(success)
                app.Image7_35.Visible=true;
            end
        end

        % Button pushed function: pushbutton53
        function pushbutton53ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",3);
            if(success)
                app.Image7_36.Visible=true;
            end
        end

        % Button pushed function: pushbutton54
        function pushbutton54ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",4);
            if(success)
                app.Image7_37.Visible=true;
            end
        end

        % Button pushed function: pushbutton55
        function pushbutton55ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",5);
            if(success)
                app.Image7_38.Visible=true;
            end
        end

        % Button pushed function: pushbutton56
        function pushbutton56ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",6);
            if(success)
                app.Image7_39.Visible=true;
            end
        end

        % Button pushed function: pushbutton57
        function pushbutton57ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",7);
            if(success)
                app.Image7_40.Visible=true;
            end
        end

        % Button pushed function: pushbutton58
        function pushbutton58ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",8);
            if(success)
                app.Image7_41.Visible=true;
            end
        end

        % Button pushed function: pushbutton59
        function pushbutton59ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",9);
            if(success)
                app.Image7_42.Visible=true;
            end
        end

        % Button pushed function: pushbutton60
        function pushbutton60ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",10);
            if(success)
                app.Image7_43.Visible=true;
            end
        end

        % Button pushed function: pushbutton61
        function pushbutton61ButtonPushed(app, event)
            [app.game,success]=app.game.makeMove("blue",11);
            if(success)
                app.Image7_44.Visible=true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI_Boardgame';
            app.figure1.Tag = 'figure1';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Tag = 'uipanel4';
            app.pushbutton51 = uibutton(app.uipanel4, 'push');
            app.pushbutton51.ButtonPushedFcn = createCallbackFcn(app, @pushbutton51ButtonPushed, true);
            app.pushbutton51.Tag = 'pushbutton51';
            app.pushbutton51.Text = '12';
            app.pushbutton52 = uibutton(app.uipanel4, 'push');
            app.pushbutton52.ButtonPushedFcn = createCallbackFcn(app, @pushbutton52ButtonPushed, true);
            app.pushbutton52.Tag = 'pushbutton52';
            app.pushbutton52.Text = '11';
            app.pushbutton53 = uibutton(app.uipanel4, 'push');
            app.pushbutton53.ButtonPushedFcn = createCallbackFcn(app, @pushbutton53ButtonPushed, true);
            app.pushbutton53.Tag = 'pushbutton53';
            app.pushbutton53.Text = '10';
            app.pushbutton54 = uibutton(app.uipanel4, 'push');
            app.pushbutton54.ButtonPushedFcn = createCallbackFcn(app, @pushbutton54ButtonPushed, true);
            app.pushbutton54.Tag = 'pushbutton54';
            app.pushbutton54.Text = '9';
            app.pushbutton55 = uibutton(app.uipanel4, 'push');
            app.pushbutton55.ButtonPushedFcn = createCallbackFcn(app, @pushbutton55ButtonPushed, true);
            app.pushbutton55.Tag = 'pushbutton55';
            app.pushbutton55.Text = '8';
            app.pushbutton56 = uibutton(app.uipanel4, 'push');
            app.pushbutton56.ButtonPushedFcn = createCallbackFcn(app, @pushbutton56ButtonPushed, true);
            app.pushbutton56.Tag = 'pushbutton56';
            app.pushbutton56.Text = '7';
            app.pushbutton57 = uibutton(app.uipanel4, 'push');
            app.pushbutton57.ButtonPushedFcn = createCallbackFcn(app, @pushbutton57ButtonPushed, true);
            app.pushbutton57.Tag = 'pushbutton57';
            app.pushbutton57.Text = '6';
            app.pushbutton58 = uibutton(app.uipanel4, 'push');
            app.pushbutton58.ButtonPushedFcn = createCallbackFcn(app, @pushbutton58ButtonPushed, true);
            app.pushbutton58.Tag = 'pushbutton58';
            app.pushbutton58.Text = '5';
            app.pushbutton59 = uibutton(app.uipanel4, 'push');
            app.pushbutton59.ButtonPushedFcn = createCallbackFcn(app, @pushbutton59ButtonPushed, true);
            app.pushbutton59.Tag = 'pushbutton59';
            app.pushbutton59.Text = '4';
            app.pushbutton60 = uibutton(app.uipanel4, 'push');
            app.pushbutton60.ButtonPushedFcn = createCallbackFcn(app, @pushbutton60ButtonPushed, true);
            app.pushbutton60.Tag = 'pushbutton60';
            app.pushbutton60.Text = '3';
            app.pushbutton61 = uibutton(app.uipanel4, 'push');
            app.pushbutton61.ButtonPushedFcn = createCallbackFcn(app, @pushbutton61ButtonPushed, true);
            app.pushbutton61.Tag = 'pushbutton61';
            app.pushbutton61.Text = '2';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Tag = 'uipanel3';
            app.pushbutton26 = uibutton(app.uipanel3, 'push');
            app.pushbutton26.ButtonPushedFcn = createCallbackFcn(app, @pushbutton26ButtonPushed, true);
            app.pushbutton26.Tag = 'pushbutton26';
            app.pushbutton26.Text = '3';
            app.pushbutton27 = uibutton(app.uipanel3, 'push');
            app.pushbutton27.ButtonPushedFcn = createCallbackFcn(app, @pushbutton27ButtonPushed, true);
            app.pushbutton27.Tag = 'pushbutton27';
            app.pushbutton27.Text = '4';
            app.pushbutton28 = uibutton(app.uipanel3, 'push');
            app.pushbutton28.ButtonPushedFcn = createCallbackFcn(app, @pushbutton28ButtonPushed, true);
            app.pushbutton28.Tag = 'pushbutton28';
            app.pushbutton28.Text = '5';
            app.pushbutton25 = uibutton(app.uipanel3, 'push');
            app.pushbutton25.ButtonPushedFcn = createCallbackFcn(app, @pushbutton25ButtonPushed, true);
            app.pushbutton25.Tag = 'pushbutton25';
            app.pushbutton25.Text = '2';
            app.pushbutton29 = uibutton(app.uipanel3, 'push');
            app.pushbutton29.ButtonPushedFcn = createCallbackFcn(app, @pushbutton29ButtonPushed, true);
            app.pushbutton29.Tag = 'pushbutton29';
            app.pushbutton29.Text = '6';
            app.pushbutton30 = uibutton(app.uipanel3, 'push');
            app.pushbutton30.ButtonPushedFcn = createCallbackFcn(app, @pushbutton30ButtonPushed, true);
            app.pushbutton30.Tag = 'pushbutton30';
            app.pushbutton30.Text = '7';
            app.pushbutton31 = uibutton(app.uipanel3, 'push');
            app.pushbutton31.ButtonPushedFcn = createCallbackFcn(app, @pushbutton31ButtonPushed, true);
            app.pushbutton31.Tag = 'pushbutton31';
            app.pushbutton31.Text = '8';
            app.pushbutton32 = uibutton(app.uipanel3, 'push');
            app.pushbutton32.ButtonPushedFcn = createCallbackFcn(app, @pushbutton32ButtonPushed, true);
            app.pushbutton32.Tag = 'pushbutton32';
            app.pushbutton32.Text = '9';
            app.pushbutton33 = uibutton(app.uipanel3, 'push');
            app.pushbutton33.ButtonPushedFcn = createCallbackFcn(app, @pushbutton33ButtonPushed, true);
            app.pushbutton33.Tag = 'pushbutton33';
            app.pushbutton33.Text = '10';
            app.pushbutton34 = uibutton(app.uipanel3, 'push');
            app.pushbutton34.ButtonPushedFcn = createCallbackFcn(app, @pushbutton34ButtonPushed, true);
            app.pushbutton34.Tag = 'pushbutton34';
            app.pushbutton34.Text = '11';
            app.pushbutton35 = uibutton(app.uipanel3, 'push');
            app.pushbutton35.ButtonPushedFcn = createCallbackFcn(app, @pushbutton35ButtonPushed, true);
            app.pushbutton35.Tag = 'pushbutton35';
            app.pushbutton35.Text = '12';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Tag = 'uipanel5';
            app.pushbutton39 = uibutton(app.uipanel5, 'push');
            app.pushbutton39.ButtonPushedFcn = createCallbackFcn(app, @pushbutton39ButtonPushed, true);
            app.pushbutton39.Tag = 'pushbutton39';
            app.pushbutton39.Text = '12';
            app.pushbutton40 = uibutton(app.uipanel5, 'push');
            app.pushbutton40.ButtonPushedFcn = createCallbackFcn(app, @pushbutton40ButtonPushed, true);
            app.pushbutton40.Tag = 'pushbutton40';
            app.pushbutton40.Text = '11';
            app.pushbutton41 = uibutton(app.uipanel5, 'push');
            app.pushbutton41.ButtonPushedFcn = createCallbackFcn(app, @pushbutton41ButtonPushed, true);
            app.pushbutton41.Tag = 'pushbutton41';
            app.pushbutton41.Text = '10';
            app.pushbutton42 = uibutton(app.uipanel5, 'push');
            app.pushbutton42.ButtonPushedFcn = createCallbackFcn(app, @pushbutton42ButtonPushed, true);
            app.pushbutton42.Tag = 'pushbutton42';
            app.pushbutton42.Text = '9';
            app.pushbutton43 = uibutton(app.uipanel5, 'push');
            app.pushbutton43.ButtonPushedFcn = createCallbackFcn(app, @pushbutton43ButtonPushed, true);
            app.pushbutton43.Tag = 'pushbutton43';
            app.pushbutton43.Text = '8';
            app.pushbutton44 = uibutton(app.uipanel5, 'push');
            app.pushbutton44.ButtonPushedFcn = createCallbackFcn(app, @pushbutton44ButtonPushed, true);
            app.pushbutton44.Tag = 'pushbutton44';
            app.pushbutton44.Text = '7';
            app.pushbutton45 = uibutton(app.uipanel5, 'push');
            app.pushbutton45.ButtonPushedFcn = createCallbackFcn(app, @pushbutton45ButtonPushed, true);
            app.pushbutton45.Tag = 'pushbutton45';
            app.pushbutton45.Text = '6';
            app.pushbutton46 = uibutton(app.uipanel5, 'push');
            app.pushbutton46.ButtonPushedFcn = createCallbackFcn(app, @pushbutton46ButtonPushed, true);
            app.pushbutton46.Tag = 'pushbutton46';
            app.pushbutton46.Text = '5';
            app.pushbutton47 = uibutton(app.uipanel5, 'push');
            app.pushbutton47.ButtonPushedFcn = createCallbackFcn(app, @pushbutton47ButtonPushed, true);
            app.pushbutton47.Tag = 'pushbutton47';
            app.pushbutton47.Text = '4';
            app.pushbutton48 = uibutton(app.uipanel5, 'push');
            app.pushbutton48.ButtonPushedFcn = createCallbackFcn(app, @pushbutton48ButtonPushed, true);
            app.pushbutton48.Tag = 'pushbutton48';
            app.pushbutton48.Text = '3';
            app.pushbutton49 = uibutton(app.uipanel5, 'push');
            app.pushbutton49.ButtonPushedFcn = createCallbackFcn(app, @pushbutton49ButtonPushed, true);
            app.pushbutton49.Tag = 'pushbutton49';
            app.pushbutton49.Text = '2';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.pushbutton2 = uibutton(app.uipanel1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2ButtonPushed, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = '2';
            app.pushbutton3 = uibutton(app.uipanel1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3ButtonPushed, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = '3';
            app.pushbutton4 = uibutton(app.uipanel1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4ButtonPushed, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Text = '4';
            app.pushbutton5 = uibutton(app.uipanel1, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton5ButtonPushed, true);
            app.pushbutton5.Tag = 'pushbutton5';
            app.pushbutton5.Text = '5';
            app.pushbutton6 = uibutton(app.uipanel1, 'push');
            app.pushbutton6.ButtonPushedFcn = createCallbackFcn(app, @pushbutton6ButtonPushed, true);
            app.pushbutton6.Tag = 'pushbutton6';
            app.pushbutton6.Text = '6';
            app.pushbutton7 = uibutton(app.uipanel1, 'push');
            app.pushbutton7.ButtonPushedFcn = createCallbackFcn(app, @pushbutton7ButtonPushed, true);
            app.pushbutton7.Tag = 'pushbutton7';
            app.pushbutton7.Text = '7';
            app.pushbutton8 = uibutton(app.uipanel1, 'push');
            app.pushbutton8.ButtonPushedFcn = createCallbackFcn(app, @pushbutton8ButtonPushed, true);
            app.pushbutton8.Tag = 'pushbutton8';
            app.pushbutton8.Text = '8';
            app.pushbutton9 = uibutton(app.uipanel1, 'push');
            app.pushbutton9.ButtonPushedFcn = createCallbackFcn(app, @pushbutton9ButtonPushed, true);
            app.pushbutton9.Tag = 'pushbutton9';
            app.pushbutton9.Text = '9';
            app.pushbutton10 = uibutton(app.uipanel1, 'push');
            app.pushbutton10.ButtonPushedFcn = createCallbackFcn(app, @pushbutton10ButtonPushed, true);
            app.pushbutton10.Tag = 'pushbutton10';
            app.pushbutton10.Text = '10';
            app.pushbutton11 = uibutton(app.uipanel1, 'push');
            app.pushbutton11.ButtonPushedFcn = createCallbackFcn(app, @pushbutton11ButtonPushed, true);
            app.pushbutton11.Tag = 'pushbutton11';
            app.pushbutton11.Text = '11';
            app.pushbutton23 = uibutton(app.uipanel1, 'push');
            app.pushbutton23.ButtonPushedFcn = createCallbackFcn(app, @pushbutton23ButtonPushed, true);
            app.pushbutton23.Tag = 'pushbutton23';
            app.pushbutton23.Text = '12';
            app.Image7 = uiimage(app.uipanel1);
            app.Image7.Visible = 'off';
            app.Image7.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_2 = uiimage(app.uipanel1);
            app.Image7_2.Visible = 'off';
            app.Image7_2.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_5 = uiimage(app.uipanel1);
            app.Image7_5.Visible = 'off';
            app.Image7_5.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_6 = uiimage(app.uipanel1);
            app.Image7_6.Visible = 'off';
            app.Image7_6.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_7 = uiimage(app.uipanel1);
            app.Image7_7.Visible = 'off';
            app.Image7_7.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_8 = uiimage(app.uipanel1);
            app.Image7_8.Visible = 'off';
            app.Image7_8.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_9 = uiimage(app.uipanel1);
            app.Image7_9.Visible = 'off';
            app.Image7_9.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_10 = uiimage(app.uipanel1);
            app.Image7_10.Visible = 'off';
            app.Image7_10.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_11 = uiimage(app.uipanel1);
            app.Image7_11.Visible = 'off';
            app.Image7_11.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_12 = uiimage(app.uipanel1);
            app.Image7_12.Visible = 'off';
            app.Image7_12.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_13 = uiimage(app.uipanel1);
            app.Image7_13.Visible = 'off';
            app.Image7_13.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = 'Alea_1.png';
            app.Image2 = uiimage(app.figure1);
            app.Image2.ImageSource = 'Alea_2.png';
            app.Image3 = uiimage(app.figure1);
            app.Image3.ImageSource = 'Alea_3.png';
            app.Image4 = uiimage(app.figure1);
            app.Image4.ImageSource = 'Alea_4.png';
            app.Image5 = uiimage(app.figure1);
            app.Image5.ImageSource = 'Alea_5.png';
            app.Image6 = uiimage(app.figure1);
            app.PenaltiesLabel = uilabel(app.figure1);
            app.PenaltiesLabel.Text = 'Penalties:';
            app.NLabel = uilabel(app.figure1);
            app.NLabel.Text = 'N';
            app.Image7_4 = uiimage(app.figure1);
            app.Image7_4.Visible = 'off';
            app.Image7_4.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_3 = uiimage(app.figure1);
            app.Image7_3.Visible = 'off';
            app.Image7_3.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_14 = uiimage(app.figure1);
            app.Image7_14.Visible = 'off';
            app.Image7_14.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_15 = uiimage(app.figure1);
            app.Image7_15.Visible = 'off';
            app.Image7_15.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_16 = uiimage(app.figure1);
            app.Image7_16.Visible = 'off';
            app.Image7_16.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_17 = uiimage(app.figure1);
            app.Image7_17.Visible = 'off';
            app.Image7_17.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_18 = uiimage(app.figure1);
            app.Image7_18.Visible = 'off';
            app.Image7_18.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_19 = uiimage(app.figure1);
            app.Image7_19.Visible = 'off';
            app.Image7_19.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_20 = uiimage(app.figure1);
            app.Image7_20.Visible = 'off';
            app.Image7_20.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_21 = uiimage(app.figure1);
            app.Image7_21.Visible = 'off';
            app.Image7_21.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_22 = uiimage(app.figure1);
            app.Image7_22.Visible = 'off';
            app.Image7_22.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_23 = uiimage(app.figure1);
            app.Image7_23.Visible = 'off';
            app.Image7_23.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_24 = uiimage(app.figure1);
            app.Image7_24.Visible = 'off';
            app.Image7_24.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_25 = uiimage(app.figure1);
            app.Image7_25.Visible = 'off';
            app.Image7_25.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_26 = uiimage(app.figure1);
            app.Image7_26.Visible = 'off';
            app.Image7_26.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_27 = uiimage(app.figure1);
            app.Image7_27.Visible = 'off';
            app.Image7_27.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_28 = uiimage(app.figure1);
            app.Image7_28.Visible = 'off';
            app.Image7_28.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_29 = uiimage(app.figure1);
            app.Image7_29.Visible = 'off';
            app.Image7_29.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_30 = uiimage(app.figure1);
            app.Image7_30.Visible = 'off';
            app.Image7_30.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_31 = uiimage(app.figure1);
            app.Image7_31.Visible = 'off';
            app.Image7_31.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_32 = uiimage(app.figure1);
            app.Image7_32.Visible = 'off';
            app.Image7_32.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_33 = uiimage(app.figure1);
            app.Image7_33.Visible = 'off';
            app.Image7_33.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_34 = uiimage(app.figure1);
            app.Image7_34.Visible = 'off';
            app.Image7_34.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_35 = uiimage(app.figure1);
            app.Image7_35.Visible = 'off';
            app.Image7_35.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_36 = uiimage(app.figure1);
            app.Image7_36.Visible = 'off';
            app.Image7_36.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_37 = uiimage(app.figure1);
            app.Image7_37.Visible = 'off';
            app.Image7_37.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_38 = uiimage(app.figure1);
            app.Image7_38.Visible = 'off';
            app.Image7_38.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_39 = uiimage(app.figure1);
            app.Image7_39.Visible = 'off';
            app.Image7_39.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_40 = uiimage(app.figure1);
            app.Image7_40.Visible = 'off';
            app.Image7_40.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_41 = uiimage(app.figure1);
            app.Image7_41.Visible = 'off';
            app.Image7_41.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_42 = uiimage(app.figure1);
            app.Image7_42.Visible = 'off';
            app.Image7_42.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_43 = uiimage(app.figure1);
            app.Image7_43.Visible = 'off';
            app.Image7_43.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            app.Image7_44 = uiimage(app.figure1);
            app.Image7_44.Visible = 'off';
            app.Image7_44.ImageSource = 'png-transparent-x-mark-red-cross-miscellaneous-angle-monochrome.png';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Updated(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_Boardgame_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end