classdef waiting_room < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        StartGameButton   matlab.ui.control.Button
        Label             matlab.ui.control.Label
        PlayerCountLabel  matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Button            matlab.ui.control.Button
    end

    
    properties (Access = private)
        g; % Description
        t;
    end
    
    methods (Access = private)
        
        function app=step(app)
            temp=game.getCurrentValuesS();
            app.Label.Text=temp(6)+"";
            if(temp(5)==1)
                app.t.stop();
                GUI_Updated(app.g);
                close(app.UIFigure);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            temp=game.getCurrentValuesS();
            if(temp(5)==1)
                %Run code to set all the waiting room options to be visible
                while(temp(5)==1)
                    temp=game.getCurrentValuesS();
                end
            end
            app.g=game();
            app.Label_2.Text=app.g.player.playerTurn+"";
            app.t = timer;
            app.t.TasksToExecute= 100000000*100000000;
            app.t.Period=1;
            app.t.TimerFcn = @(~,~) app.step();
            app.t.ExecutionMode = 'fixedSpacing';
            start(app.t);
        end

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            temp=game.getCurrentValuesS();
            thingSpeakWrite(1302152,[randi(6,1,6),1],'WriteKey','NGW99VVOQPSVSISN')
            thingSpeakWrite(1302961,[0,0,0,0,1,temp(6),0],'WriteKey','5PMM9O7QNFYBH0OH')
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.t.stop();
                delete(app.t);
            catch
            end
            delete(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Rules_Page_1();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.PlayerCountLabel = uilabel(app.UIFigure);
            app.PlayerCountLabel.Text = 'Player Count:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '0';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waiting_room

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end